classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        RightPanel     matlab.ui.container.Panel
        Button         matlab.ui.control.Button
        brahimEthemBildiriciProje3TextDuyguAnaliziLabel  matlab.ui.control.Label
        TahminLabel    matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
        TahminButton   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Property % Description
        Property2 % Description
        x % Description
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            emb = fastTextWordEmbedding;
filenameTrain = "TrkceTwit_1.csv";
textName = "Tweets";
labelName = "Duygu";
ttdsTrain = tabularTextDatastore(filenameTrain,'SelectedVariableNames',[textName labelName]);
ttdsTrain.ReadSize = 8;
preview(ttdsTrain)

labels = readLabels(ttdsTrain,labelName);
classNames = unique(labels);
numObservations = numel(labels);


sequenceLength = 14;
tdsTrain = transform(ttdsTrain, @(data) transformTextData(data,sequenceLength,emb,classNames))

preview(tdsTrain)


numFeatures = emb.Dimension;
inputSize = [1 sequenceLength numFeatures];
numFilters = 200;

ngramLengths = [2 3 4 5];
numBlocks = numel(ngramLengths);

numClasses = numel(classNames);


layer = imageInputLayer(inputSize,'Normalization','none','Name','input');
lgraph = layerGraph(layer);


for j = 1:numBlocks
    N = ngramLengths(j);
    
    block = [
        convolution2dLayer([1 N],numFilters,'Name',"conv"+N,'Padding','same')
        batchNormalizationLayer('Name',"bn"+N)
        reluLayer('Name',"relu"+N)
        dropoutLayer(0.2,'Name',"drop"+N)
        maxPooling2dLayer([1 sequenceLength],'Name',"max"+N)];
    
    lgraph = addLayers(lgraph,block);
    lgraph = connectLayers(lgraph,'input',"conv"+N);
end


figure
plot(lgraph)
title("Network Architecture")

layers = [
    depthConcatenationLayer(numBlocks,'Name','depth')
    fullyConnectedLayer(numClasses,'Name','fc')
    softmaxLayer('Name','soft')
    classificationLayer('Name','classification')];

lgraph = addLayers(lgraph,layers);

figure
plot(lgraph)
title("Network Architecture")


for j = 1:numBlocks
    N = ngramLengths(j);
    lgraph = connectLayers(lgraph,"max"+N,"depth/in"+j);
end

figure
plot(lgraph)
title("Network Architecture")

%  TRAIN

miniBatchSize = 128;
numIterationsPerEpoch = floor(numObservations/miniBatchSize);

options = trainingOptions('adam', ...
    'MiniBatchSize',miniBatchSize, ...
    'Shuffle','never', ...
    'InitialLearnRate',1', ...
    'Plots','training-progress', ...
    'MaxEpochs',1, ...
    'Verbose',false);

net = trainNetwork(tdsTrain,lgraph,options);


        end

        % Button pushed function: TahminButton
        function TahminButtonPushed(app, event)
        reportsNew = [ 
    "Ekonomi çok iyi güzel kardeş."
    ];
XNew = preprocessText(reportsNew,sequenceLength,emb);
labelsNew = classify(net,XNew);
asd="d";

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
        app.TahminLabel.Text=app.x;   
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TahminButton = uibutton(app.RightPanel, 'push');
            app.TahminButton.ButtonPushedFcn = createCallbackFcn(app, @TahminButtonPushed, true);
            app.TahminButton.Text = 'Tahmin';
            app.TextAreaLabel = uilabel(app.RightPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.RightPanel);
            app.TahminLabel = uilabel(app.RightPanel);
            app.TahminLabel.Text = 'Tahmin: ';
            app.brahimEthemBildiriciProje3TextDuyguAnaliziLabel = uilabel(app.RightPanel);
            app.brahimEthemBildiriciProje3TextDuyguAnaliziLabel.Text = 'İbrahim Ethem Bildirici - Proje3 Text Duygu Analizi';
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end